classdef CrystalCropperGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OutputFolderField    matlab.ui.control.EditField
        SavingFolderLabel    matlab.ui.control.Label
        SelectFolderButton   matlab.ui.control.Button
        SavedCounterLabel    matlab.ui.control.Label
        NextFrameButton      matlab.ui.control.Button
        PreviousFrameButton  matlab.ui.control.Button
        ConfirmCropButton    matlab.ui.control.Button
        FrameLabel           matlab.ui.control.Label
        CropCrystalButton    matlab.ui.control.Button
        FrameSlider          matlab.ui.control.Slider
        LoadVideoButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

   properties (Access = private)
    VideoObject     % To hold the VideoReader object
    CroppedImage    % To store the currently cropped image
    TotalFrames     % To store the total number of frames
    VideoFileName   % To store the name of the video file
    RectangleROI    % To hold the interactive rectangle object
    SavedCrystalCount = 0; % Counter for saved crystals
    OutputFolderPath = ''; % To store the selected save destination
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure the confirm button is disabled on startup
            app.ConfirmCropButton.Enable = 'off';
            % Set a default output path when the app starts
            app.OutputFolderPath = fullfile(pwd, 'Cropped_Crystals'); % pwd is the current folder
            app.OutputFolderField.Value = app.OutputFolderPath;
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
   
            % Open file selection dialog for video files
            [file, path] = uigetfile({'*.mp4';'*.avi';'*.mov';'*.*'}, 'Select a Video File');
        
            % Check if the user selected a file
            if isequal(file, 0)
                disp('User canceled the selection.');
                return;
            end

            % Reset the counter for the new session
            app.SavedCrystalCount = 0;
            app.SavedCounterLabel.Text = 'Crystals Saved: 0';
        
            % Construct the full file path
            fullPath = fullfile(path, file);
            app.VideoFileName = file; % Store filename for saving later
        
            % Create a VideoReader object and store it in the app's properties
            app.VideoObject = VideoReader(fullPath);
            app.TotalFrames = app.VideoObject.NumFrames;
        
            % Configure the slider
            app.FrameSlider.Limits = [1 app.TotalFrames];
            app.FrameSlider.Value = 1;
            app.FrameSlider.MajorTicks = round(linspace(1, app.TotalFrames, 10)); % Optional: makes slider look nice
        
            % Read and display the first frame
            currentFrame = readFrame(app.VideoObject);
            imshow(currentFrame, 'Parent', app.UIAxes);
            app.FrameLabel.Text = ['Frame: 1 / ' num2str(app.TotalFrames)];
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
                    % Get the current value of the slider (frame number)
            frameNumber = round(app.FrameSlider.Value);
        
            % Ensure the video object exists
            if ~isempty(app.VideoObject)
                % Read the specific frame
                currentFrame = read(app.VideoObject, frameNumber);
                % Display the frame
                imshow(currentFrame, 'Parent', app.UIAxes);
                % Update the label
                app.FrameLabel.Text = ['Frame: ' num2str(frameNumber) ' / ' num2str(app.TotalFrames)];
            end
        end

        % Button pushed function: CropCrystalButton
        function CropCrystalButtonPushed(app, event)

        % Check if an image is loaded in the axes
        if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Please load a video first.', 'No Image');
            return;
        end
        
        % Deactivate zoom/pan to ensure drawing works correctly
        zoom(app.UIAxes, 'off');
        pan(app.UIAxes, 'off');
        
        % If an old rectangle exists, delete it before drawing a new one
        if ~isempty(app.RectangleROI) && isvalid(app.RectangleROI)
            delete(app.RectangleROI);
        end
        
        % Let the user draw a rectangle and store the object in our property
        app.RectangleROI = drawrectangle(app.UIAxes);
        
        % Enable the 'Confirm Crop' button so the user can proceed
        app.ConfirmCropButton.Enable = 'on';
        
        end

        % Callback function: not associated with a component
        function SaveCropButtonPushed(app, event)
            % Check if there is a cropped image to save
    if isempty(app.CroppedImage)
        uialert(app.UIFigure, 'Please crop a crystal before saving.', 'No Cropped Image');
        return;
    end

    % Create a directory to save the images if it doesn't exist
    outputFolder = 'Cropped_Crystals';
    if ~exist(outputFolder, 'dir')
       mkdir(outputFolder);
    end

    % Generate a unique filename to avoid overwriting
    % Format: OriginalName_FrameNumber_Timestamp.png
    [~, name, ~] = fileparts(app.VideoFileName);
    frameNumber = round(app.FrameSlider.Value);
    timestamp = datestr(now, 'yyyymmdd_HHMMSSFFF');
    newFileName = sprintf('%s_frame%d_%s.png', name, frameNumber, timestamp);
    fullSavePath = fullfile(outputFolder, newFileName);

    % Save the cropped image
    imwrite(app.CroppedImage, fullSavePath);

    % Notify the user
    msgbox(['Image saved successfully as: ' newFileName], 'Success');

    % Clear the stored crop so you don't save it twice by mistake
    app.CroppedImage = [];
        end

        % Button pushed function: ConfirmCropButton
        function ConfirmCropButtonPushed(app, event)
       
    % 1. Check if a valid rectangle has been drawn
    if isempty(app.RectangleROI) || ~isvalid(app.RectangleROI)
        uialert(app.UIFigure, 'Please draw a crop area first using the "Crop Crystal" button.', 'No Selection');
        return;
    end

    % 2. Get the image data currently in the axes
    hImage = findobj(app.UIAxes, 'Type', 'image');
    if isempty(hImage)
         uialert(app.UIFigure, 'Error: Could not find the image to crop.', 'Image Error');
         return;
    end
    currentImageData = hImage.CData;

    % 3. Get the position of the drawn rectangle
    cropPosition = app.RectangleROI.Position;

    % 4. Perform the crop using the position data
    app.CroppedImage = imcrop(currentImageData, cropPosition);

    % 5. Save the cropped image (this is the logic from the old button)
    
    % Use the path from our property instead of a hard-coded name**
    outputFolder = app.OutputFolderPath;

    % Ensure the user-selected directory exists**
    if ~exist(outputFolder, 'dir')
       mkdir(outputFolder);
    end

    [~, name, ~] = fileparts(app.VideoFileName);
    frameNumber = round(app.FrameSlider.Value);
    timestamp = datestr(now, 'yyyymmdd_HHMMSSFFF');
    newFileName = sprintf('%s_frame%d_%s.png', name, frameNumber, timestamp);
    fullSavePath = fullfile(outputFolder, newFileName);
    
    imwrite(app.CroppedImage, fullSavePath);
    msgbox(['Image saved successfully as: ' newFileName], 'Success');

    % Increment the counter and update the label**
    app.SavedCrystalCount = app.SavedCrystalCount + 1;
    app.SavedCounterLabel.Text = ['Crystals Saved: ' num2str(app.SavedCrystalCount)];


    % 6. Clean up for the next operation
    delete(app.RectangleROI);       % Remove the rectangle from the image
    app.RectangleROI = [];          % Clear the property
    app.CroppedImage = [];          % Clear the stored image
    app.ConfirmCropButton.Enable = 'off'; % Disable the button again
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
       
    newValue = app.FrameSlider.Value - 1;
    % Check if we are not below the first frame
    if newValue >= 1
        app.FrameSlider.Value = newValue;
    end
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
          
    newValue = app.FrameSlider.Value + 1;
    % Check if we are not past the last frame
    if newValue <= app.FrameSlider.Limits(2)
        app.FrameSlider.Value = newValue;
    end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
    
        % Open a dialog to let the user select a folder
        % uigetdir starts in the current directory (pwd)
        selectedPath = uigetdir(pwd, 'Select an Output Folder');
        
        % Check if the user selected a folder (and didn't cancel)
        if selectedPath ~= 0
            % Store the path in our property
            app.OutputFolderPath = selectedPath;
            % Update the read-only text field to show the new path
            app.OutputFolderField.Value = app.OutputFolderPath;
        else
            % User canceled, so we do nothing and keep the old path
            disp('Folder selection canceled.');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.CropCrystalButton = uibutton(app.UIFigure, 'push');
            app.CropCrystalButton.ButtonPushedFcn = createCallbackFcn(app, @CropCrystalButtonPushed, true);
            app.CropCrystalButton.Text = 'Crop Crystal';
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame: '; '1'};
            app.ConfirmCropButton = uibutton(app.UIFigure, 'push');
            app.ConfirmCropButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCropButtonPushed, true);
            app.ConfirmCropButton.Text = 'Confirm Crop';
            app.PreviousFrameButton = uibutton(app.UIFigure, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame ';
            app.SavedCounterLabel = uilabel(app.UIFigure);
            app.SavedCounterLabel.Text = 'Crystals Saved: 0';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Output Folder';
            app.SavingFolderLabel = uilabel(app.UIFigure);
            app.SavingFolderLabel.Text = 'Saving Folder:';
            app.OutputFolderField = uieditfield(app.UIFigure, 'text');
            app.OutputFolderField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrystalCropperGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end